classdef SDD_V_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        GridLayout2     matlab.ui.container.GridLayout
        PlotButton      matlab.ui.control.Button
        ClearButton     matlab.ui.control.Button
        SaveButton      matlab.ui.control.Button
        GridLayout3     matlab.ui.container.GridLayout
        VpmyrLabel      matlab.ui.control.Label
        VpmyrEditField  matlab.ui.control.NumericEditField
        VrmyrLabel      matlab.ui.control.Label
        VrmyrEditField  matlab.ui.control.NumericEditField
        MmLabel         matlab.ui.control.Label
        MmEditField     matlab.ui.control.NumericEditField
        KpLabel         matlab.ui.control.Label
        KpEditField     matlab.ui.control.NumericEditField
        KrLabel         matlab.ui.control.Label
        KrEditField     matlab.ui.control.NumericEditField
        HpHrLabel       matlab.ui.control.Label
        HpHrEditField   matlab.ui.control.NumericEditField
        TpLabel         matlab.ui.control.Label
        TpEditField     matlab.ui.control.NumericEditField
        TrLabel         matlab.ui.control.Label
        TrEditField     matlab.ui.control.NumericEditField
        bLabel          matlab.ui.control.Label
        bEditField      matlab.ui.control.NumericEditField
        kpLabel         matlab.ui.control.Label
        kpEditField     matlab.ui.control.NumericEditField
        krLabel         matlab.ui.control.Label
        krEditField     matlab.ui.control.NumericEditField
        mLabel          matlab.ui.control.Label
        mEditField      matlab.ui.control.NumericEditField
        xcpmLabel       matlab.ui.control.Label
        xcpmEditField   matlab.ui.control.NumericEditField
        xcrmLabel       matlab.ui.control.Label
        xcrmEditField   matlab.ui.control.NumericEditField
        nLabel          matlab.ui.control.Label
        Label           matlab.ui.control.Label
        GridLayout4     matlab.ui.container.GridLayout
        UIAxes          matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            m = app.mEditField.Value;
            b = app.bEditField.Value; % Parameter of Hack's law
            M = app.MmEditField.Value; % (m)
            HH = app.HpHrEditField.Value; % Hp/Hr; H=zch-zb; zch: Channel head height; zb: Base level height
            xc1 = app.xcpmEditField.Value; % xc'p,(m)
            xc2 = app.xcrmEditField.Value; % xc'r,(m)
            K1 = app.KpEditField.Value; % Kp: yr^(-1), Erosion coefficient
            K2 = app.KrEditField.Value; % Kr
            T1 = app.TpEditField.Value; % Tp: Tortuosity coefficient
            T2 = app.TrEditField.Value; % Tr
            k1 = app.kpEditField.Value; % kp: Parameter of Hack's law. k = km^(2-b) = 1000^(2-b)*m^(2-b)
            k2 = app.krEditField.Value; % kr: b=1.4;  63.1*?
            V1 = app.VpmyrEditField.Value; % Vp,(m/yr)
            V2 = app.VrmyrEditField.Value; % Vr,(m/yr)
            
            C = (K2/K1)/HH*(T2/T1)^(b*m-1)*(k2/k1)^(m);    
            C1 = K1^(-1)*T1^(1-b*m)*k1^(-m); % Cp
            C2 = K2^(-1)*T2^(1-b*m)*k2^(-m); % Cr
            xcM1 = xc1/M; % xc'p/M
            xcM2 = xc2/M; % xc'r/M
            
            dx = 1e-2; 
            xx1 = 0:dx:1; 
            yy1 = zeros(1,numel(xx1)); 
            xx2 = 0:dx:1; 
            yy2 = zeros(1,numel(xx2));             
            tol = 1e-4; 
            xcM_min = 1e-6; 
            
            for i = 1:numel(xx1)
            
                x = xx1(i);
            
                u = 2*max(xcM1,xcM2);
                v = 1-u;
            
                eu = myfun1(u,x,b,m,M,C,C1,C2,...
                            V1,V2,xcM1,xcM2,xcM_min);
            
                ev = myfun1(v,x,b,m,M,C,C1,C2,...
                            V1,V2,xcM1,xcM2,xcM_min);
            
                if ~isfinite(eu) || ~isfinite(ev)
                    yy1(i)=NaN;
                    continue;
                end
            
                if eu*ev > 0
                    yy1(i)=NaN;
                    continue;
                end
            
                try
            
                    f = @(y) myfun1(y,x,b,m,M,C,C1,C2,...
                                    V1,V2,xcM1,xcM2,xcM_min);
            
                    yy1(i)=fzero(f,[u,v]);
            
                catch
            
                    yy1(i)=NaN;
            
                end
            
            end
                        
                        
            for i = 1:numel(xx2)
            
                x = xx2(i);
            
                u = 2*max(xcM1,xcM2);
                v = 1-u;
            
                eu = myfun2(u,x,b,m,M,C,C1,C2,...
                            V1,V2,xcM1,xcM2,xcM_min);
            
                ev = myfun2(v,x,b,m,M,C,C1,C2,...
                            V1,V2,xcM1,xcM2,xcM_min);
            
                if ~isfinite(eu) || ~isfinite(ev)
                    yy2(i)=NaN;
                    continue;
                end
            
                if eu*ev > 0
                    yy2(i)=NaN;
                    continue;
                end
            
                try
            
                    f = @(y) myfun2(y,x,b,m,M,C,C1,C2,...
                                    V1,V2,xcM1,xcM2,xcM_min);
            
                    yy2(i)=fzero(f,[u,v]);
            
                catch
            
                    yy2(i)=NaN;
            
                end
            
            end
            
            plot(app.UIAxes,xx1,yy1);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            hold(app.UIAxes, 'on');
    
            plot(app.UIAxes2,xx2,yy2);  
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.XDir = 'reverse';
            hold(app.UIAxes2, 'on');            
           
            function I = safeIntegral(fun,a,b)
            
                if b <= a
                    I = 0;
                    return;
                end
            
                try
                I = integral(fun,a,b,...
                    'RelTol',1e-5,...
                    'AbsTol',1e-8,...
                    'ArrayValued',true);
            
                    if ~isfinite(I)
                        I = NaN;
                    end
            
                catch
                    I = NaN;
                end
            
            end
                        
                        
            function output = myfun1(y,x,b,m,M,C,C1,C2,V1,V2,xcM1,xcM2,xcM_min)
            
                upper2 = y - xcM2 - xcM_min;
                upper1 = 1 - y - xcM1 - xcM_min;
            
                if upper2 <= 0 || upper1 <= 0
                    output = NaN;
                    return;
                end
            
                den2 = @(t2) ...
                    M^(b*m).*(y-t2).^(b*m) ...
                    - C2.*(V1.*t2+V2.*(1-t2));
            
                den1 = @(t1) ...
                    M^(b*m).*(1-y-t1).^(b*m) ...
                    + C1.*(V1.*(1-t1)+V2.*t1);
            
                fun2 = @(t2) ...
                    (x+t2.*(1-x))./den2(t2);
            
                fun1 = @(t1) ...
                    (1-t1.*(1-x))./den1(t1);
            
                tt = linspace(0,upper2,2000);
                dtest = den2(tt);
                
                if any(abs(dtest)<1e-10)
                    output = NaN;
                    return;
                end
            
                poleIndex = find( ...
                    den2(tt(1:end-1)).*den2(tt(2:end)) < 0,...
                    1);
            
                if isempty(poleIndex)
            
                    I2 = safeIntegral(fun2,0,upper2);
            
                else
            
                    tpole = (tt(poleIndex)+tt(poleIndex+1))/2;
            
                    epsPole = max(1e-6,upper2*1e-5);
            
                    I2a = safeIntegral(fun2,0,tpole-epsPole);
            
                    I2b = safeIntegral(fun2,tpole+epsPole,upper2);
            
                    I2 = I2a + I2b;
            
                end
            
                I1 = safeIntegral(fun1,0,upper1);
            
                if ~isfinite(I1) || ~isfinite(I2)
                    output = NaN;
                    return;
                end
            
                if abs(I1) < 1e-12
                    output = NaN;
                    return;
                end
            
                output = I2/I1 - C;
            
            end
                        
            function output = myfun2(y,x,b,m,M,C,C1,C2,V1,V2,xcM1,xcM2,xcM_min)
            
                upper2 = y - xcM2 - xcM_min;
                upper1 = 1 - y - xcM1 - xcM_min;
            
                if upper2 <= 0 || upper1 <= 0
                    output = NaN;
                    return;
                end
            
                den2 = @(t2) ...
                    M^(b*m).*(y-t2).^(b*m) ...
                    - C2.*(V1.*t2+V2.*(1-t2));
            
                den1 = @(t1) ...
                    M^(b*m).*(1-y-t1).^(b*m) ...
                    + C1.*(V1.*(1-t1)+V2.*t1);
            
                fun2 = @(t2) ...
                    (1+t2.*(x-1))./den2(t2);
            
                fun1 = @(t1) ...
                    (x-t1.*(x-1))./den1(t1);
            
                tt = linspace(0,upper2,2000);
                dtest = den2(tt);
                
                if any(abs(dtest)<1e-10)
                    output = NaN;
                    return;
                end
                            
                poleIndex = find( ...
                    den2(tt(1:end-1)).*den2(tt(2:end)) < 0,...
                    1);
            
                if isempty(poleIndex)
            
                    I2 = safeIntegral(fun2,0,upper2);
            
                else
            
                    tpole = (tt(poleIndex)+tt(poleIndex+1))/2;
            
                    epsPole = max(1e-6,upper2*1e-5);
            
                    I2a = safeIntegral(fun2,0,tpole-epsPole);
            
                    I2b = safeIntegral(fun2,tpole+epsPole,upper2);
            
                    I2 = I2a + I2b;
            
                end
            
                I1 = safeIntegral(fun1,0,upper1);
            
                if ~isfinite(I1) || ~isfinite(I2)
                    output = NaN;
                    return;
                end
            
                if abs(I1) < 1e-12
                    output = NaN;
                    return;
                end
            
                output = I2/I1 - C;
            
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

                cla(app.UIAxes);
                cla(app.UIAxes2);                

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                [filename, pathname] = uiputfile('*.pdf', 'Save Figure As PDF');                
                if isequal(filename, 0) || isequal(pathname, 0)
                    return;
                end                
                fullpath = fullfile(pathname, filename);                
                try
                    fig = figure('Visible', 'off', 'Position', [100 100 1200 900]);
                    ax1 = subplot(1,2,1);
                    lineObjs = findobj(app.UIAxes, 'Type', 'line');
                    if ~isempty(lineObjs)
                        for i = 1:length(lineObjs)
                            xdata = lineObjs(i).XData;
                            ydata = lineObjs(i).YData;
                            plot(xdata, ydata, 'Color', lineObjs(i).Color, ...
                                 'LineStyle', lineObjs(i).LineStyle, ...
                                 'LineWidth', lineObjs(i).LineWidth);
                            hold on;
                        end
                    end
                    xlabel('Ur/Up');
                    ylabel('Dr/M');
                    xlim([0 1]);
                    ylim([0 1]);
                    grid on;
                    ax2 = subplot(1,2,2);
                    lineObjs2 = findobj(app.UIAxes2, 'Type', 'line');
                    if ~isempty(lineObjs2)
                        for i = 1:length(lineObjs2)
                            xdata = lineObjs2(i).XData;
                            ydata = lineObjs2(i).YData;
                            plot(xdata, ydata, 'Color', lineObjs2(i).Color, ...
                                 'LineStyle', lineObjs2(i).LineStyle, ...
                                 'LineWidth', lineObjs2(i).LineWidth);
                            hold on;
                        end
                    end
                    set(gca, 'XDir', 'reverse');
                    set(gca, 'YTickLabel', []);
                    xlabel('Up/Ur');
                    ylabel('');
                    xlim([0 1]);
                    ylim([0 1]);
                    grid on;
                    set(ax1, 'Position', [0.08 0.15 0.42 0.75]);
                    set(ax2, 'Position', [0.50 0.15 0.42 0.75]);
                    print(fig, fullpath, '-dpdf', '-bestfit');
                    close(fig);                    
                    msgbox(['Figure saved successfully to: ' fullpath], 'Save Complete');                    
                catch ME
                    errordlg(['Failed to save figure: ' ME.message], 'Save Error');
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.GridLayout2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.VpmyrLabel = uilabel(app.GridLayout3);
            app.VpmyrLabel.Text = 'Vp (m/yr) =';
            app.VpmyrEditField = uieditfield(app.GridLayout3, 'numeric');
            app.VpmyrEditField.Value = 0.001;
            app.VrmyrLabel = uilabel(app.GridLayout3);
            app.VrmyrLabel.Text = 'Vr (m/yr) =';
            app.VrmyrEditField = uieditfield(app.GridLayout3, 'numeric');
            app.VrmyrEditField.Value = 0.001;
            app.MmLabel = uilabel(app.GridLayout3);
            app.MmLabel.Text = 'M (m) =';
            app.MmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MmEditField.Value = 50000;
            app.KpLabel = uilabel(app.GridLayout3);
            app.KpLabel.Text = 'Kp =';
            app.KpEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KpEditField.Value = 6e-06;
            app.KrLabel = uilabel(app.GridLayout3);
            app.KrLabel.Text = 'Kr =';
            app.KrEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KrEditField.Value = 6e-06;
            app.HpHrLabel = uilabel(app.GridLayout3);
            app.HpHrLabel.Text = 'Hp/Hr =';
            app.HpHrEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HpHrEditField.Value = 1;
            app.TpLabel = uilabel(app.GridLayout3);
            app.TpLabel.Text = 'Tp =';
            app.TpEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TpEditField.Value = 1.2;
            app.TrLabel = uilabel(app.GridLayout3);
            app.TrLabel.Text = 'Tr =';
            app.TrEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TrEditField.Value = 1.2;
            app.bLabel = uilabel(app.GridLayout3);
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.GridLayout3, 'numeric');
            app.bEditField.Value = 1.2;
            app.kpLabel = uilabel(app.GridLayout3);
            app.kpLabel.Text = 'kp =';
            app.kpEditField = uieditfield(app.GridLayout3, 'numeric');
            app.kpEditField.Value = 400;
            app.krLabel = uilabel(app.GridLayout3);
            app.krLabel.Text = 'kr =';
            app.krEditField = uieditfield(app.GridLayout3, 'numeric');
            app.krEditField.Value = 400;
            app.mLabel = uilabel(app.GridLayout3);
            app.mLabel.Text = 'm =';
            app.mEditField = uieditfield(app.GridLayout3, 'numeric');
            app.mEditField.Value = 0.45;
            app.xcpmLabel = uilabel(app.GridLayout3);
            app.xcpmLabel.Text = 'xcp (m) =';
            app.xcpmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xcpmEditField.Value = 500;
            app.xcrmLabel = uilabel(app.GridLayout3);
            app.xcrmLabel.Text = 'xcr (m) =';
            app.xcrmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xcrmEditField.Value = 500;
            app.nLabel = uilabel(app.GridLayout3);
            app.nLabel.Text = 'n =';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Ur/Up')
            ylabel(app.UIAxes, 'Dr/M')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Up/Ur')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDD_V_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end